classdef InterpolationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        UIAxes                     matlab.ui.control.UIAxes
        InterpolationcontrolPanel  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        UIAxes2                    matlab.ui.control.UIAxes
        NaEditField                matlab.ui.control.NumericEditField
        NaEditFieldLabel           matlab.ui.control.Label
        SiO_2EditField             matlab.ui.control.NumericEditField
        SiO_2EditFieldLabel        matlab.ui.control.Label
        TeplotaEditField           matlab.ui.control.NumericEditField
        TeplotaEditFieldLabel      matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        GetZvalueButton            matlab.ui.control.Button
        VartypeDropDown            matlab.ui.control.DropDown
        VartypeDropDownLabel       matlab.ui.control.Label
        UpdateDBButton             matlab.ui.control.Button
    end

    
    properties (Access = private)
        FG; % Description
        XVal;
        YVal;
        TVal;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FG=FitGuide;
            app.FG.Load;
            app.FG.AssAxis(app.UIAxes,app.UIAxes2);
            app.FG.Draw;
            app.ZEditFieldLabel.Text="Viscosity";
        end

        % Value changed function: NaEditField
        function NaEditFieldValueChanged(app, event)
            app.XVal = app.NaEditField.Value;
%             app.FG.Draw(app.XVal,app.YVal,app.TVal);
        end

        % Value changed function: SiO_2EditField
        function SiO_2EditFieldValueChanged(app, event)
            app.YVal = app.SiO_2EditField.Value;
%             app.XVal = app.NaEditField.Value;
        end

        % Value changed function: TeplotaEditField
        function TeplotaEditFieldValueChanged(app, event)
            app.TVal = app.TeplotaEditField.Value;
            
        end

        % Button pushed function: GetZvalueButton
        function GetZvalueButtonPushed(app, event)
            app.FG.InterDraw(app.XVal,app.YVal,app.TVal);
            app.ZEditField.Value=app.FG.FinY;
        end

        % Value changed function: VartypeDropDown
        function VartypeDropDownValueChanged(app, event)
            type = app.VartypeDropDown.Value;
            app.FG.SetType(type);
            app.FG.Draw;
            
            if type=="v"
                app.ZEditFieldLabel.Text="Viscosity";
            else
                app.ZEditFieldLabel.Text="Density";
            end
        end

        % Button pushed function: UpdateDBButton
        function UpdateDBButtonPushed(app, event)
            tic;
            app.FG.Run;
            app.FG.Draw;
            elapsedtime=toc;
            message=sprintf('Your model was updated.\nIt took: %0.4f seconds',elapsedtime);
            uialert(app.UIFigure,message,'Warning','Icon','success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.InterpolationcontrolPanel = uipanel(app.GridLayout);
            app.InterpolationcontrolPanel.Title = 'Interpolation control';
            app.GridLayout2 = uigridlayout(app.InterpolationcontrolPanel);
            app.UIAxes2 = uiaxes(app.GridLayout2);
            title(app.UIAxes2, 'Temperature profile')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.NaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NaEditField.ValueChangedFcn = createCallbackFcn(app, @NaEditFieldValueChanged, true);
            app.NaEditFieldLabel = uilabel(app.GridLayout2);
            app.NaEditFieldLabel.Text = 'Na^{+}';
            app.SiO_2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.SiO_2EditField.ValueChangedFcn = createCallbackFcn(app, @SiO_2EditFieldValueChanged, true);
            app.SiO_2EditFieldLabel = uilabel(app.GridLayout2);
            app.SiO_2EditFieldLabel.Text = 'SiO_{2}';
            app.TeplotaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TeplotaEditField.ValueChangedFcn = createCallbackFcn(app, @TeplotaEditFieldValueChanged, true);
            app.TeplotaEditFieldLabel = uilabel(app.GridLayout2);
            app.TeplotaEditFieldLabel.Text = 'Teplota';
            app.ZEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ZEditFieldLabel = uilabel(app.GridLayout2);
            app.ZEditFieldLabel.Text = 'Z';
            app.GetZvalueButton = uibutton(app.GridLayout2, 'push');
            app.GetZvalueButton.ButtonPushedFcn = createCallbackFcn(app, @GetZvalueButtonPushed, true);
            app.GetZvalueButton.Text = 'Get Z value';
            app.VartypeDropDown = uidropdown(app.GridLayout);
            app.VartypeDropDown.Items = {'Viscosity', 'Density'};
            app.VartypeDropDown.ItemsData = {'v', 'd'};
            app.VartypeDropDown.ValueChangedFcn = createCallbackFcn(app, @VartypeDropDownValueChanged, true);
            app.VartypeDropDown.Value = 'v';
            app.VartypeDropDownLabel = uilabel(app.GridLayout);
            app.VartypeDropDownLabel.Text = 'Var. type';
            app.UpdateDBButton = uibutton(app.GridLayout, 'push');
            app.UpdateDBButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDBButtonPushed, true);
            app.UpdateDBButton.Text = 'Update DB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterpolationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end